classdef Aplicacao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Imagem          matlab.ui.control.UIAxes
        Start           matlab.ui.control.StateButton
        NrepetiesLabel  matlab.ui.control.Label
        nAmostras       matlab.ui.control.NumericEditField
        Calibrar        matlab.ui.control.Button
        Anelar          matlab.ui.control.UIAxes
        Medio           matlab.ui.control.UIAxes
        Indicador       matlab.ui.control.UIAxes
        Minimo          matlab.ui.control.UIAxes
        HALLPolegar     matlab.ui.control.UIAxes
        Polegar         matlab.ui.control.UIAxes
        HALLIndicador   matlab.ui.control.UIAxes
        ColetandoDados  matlab.ui.control.StateButton
    end

    properties (Access = public)
        dir='D:\ifrs\11° periodo\TCC II\MATLAB\Interface\Imagens\';
        sequencia = ['z'];
        % inicializa porta COM, Taxa 115200 bps, 8 bits de dados
        portaSerial;
        %qtdLetras = 19; % Quantidade de letras inseridas no vetor alfabeto da função random
        qtdLetras = 3;
        ValoresReferencia;%valor referencia de tensão definido na calibragem
        DesvioReferencia;%desvio padrão encontrado nas amostras usadas p/ calibragem
        StrDate; %horario de calibragem
    end
    methods (Access = private)
        %Função randon é responsável por gerar a sequencia aleatória de letras de acordo com o n° de amostra desejada 
        function random(app)
            %alfabeto = ['A', 'B', 'C', 'D', 'E', 'F', 'G', 'I', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V'];
            alfabeto = ['A', 'B', 'C'];
            for i = 1:app.qtdLetras
                contAlfa(i)=0;
            end
            i=1;
            while i <= app.qtdLetras*app.nAmostras.Value
                item = randi([1 app.qtdLetras]);
                if contAlfa(item) < app.nAmostras.Value
                    app.sequencia(i) = alfabeto(item);
                    contAlfa(item) = contAlfa(item) + 1;
                    i=i+1;
                end   
            end
           %disp(app.sequencia);
        end
 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%clear all;
            %%close all;
            %0,5s por movimento - 92quadros/mov(tava 19200) - 184quadros/mov
            %1 quadro - 13byes - 104bits - 5,42ms(taxa 19200) - 2,71ms(taxa 38400)
            
           
            disp('Iniciando o programa...');
            app.Start.Enable = 'off';
            %Inicialização da serial
                delete(instrfindall);       %%limpa todos os instrumentos abertos anteriormente na serial
                app.portaSerial = serial('COM5','BaudRate',38400,'DataBits',8);
                %57600
                % Sem paridade, 1 StopBit, Sem controle de fluxo
                set(app.portaSerial,'Parity','none','StopBits',1,'FlowControl','none');
                set(app.portaSerial,'Timeout',1); %segundos
                
                % ajusta o tamanho dos buffers de entrada e saída
                app.portaSerial.InputBufferSize = 10000;
                app.portaSerial.OutputBufferSize = 100;
                
                %%Inicia comunicação Serial
                fopen(app.portaSerial);
                pause(2);
                fprintf('\nConectado!');
                
            %Inicialização do programa
                file = sprintf('%s%s.jpg',app.dir,'Movimentos');
                image = imread(file);
                imshow(image, 'parent', app.Imagem);
            
        end

        % Button pushed function: Calibrar
        function CalibrarButtonPushed(app, event)
            %Código de Calibração
            app.Calibrar.Text = 'Calibrando';
            app.Calibrar.BackgroundColor = 'red';
            MatrizSensores=[0,0,0,0,0,0,0,0];
            
            %Inicio Captação de dados
                captarDados=1;
                %Inicia o envio do quadro pela luva
                    flushinput(app.portaSerial)
                    num = '$';
                    fwrite(app.portaSerial, num, 'int8');
                    while(app.portaSerial.BytesAvailable==0)
                    end
                    %Inicio das leituras
                        while(captarDados<500)
                            while(app.portaSerial.BytesAvailable<14)
                            end
                            %Quadro [$ Po2 Po1 In2 In1 Me2 Me1 An2 An1 Mi2 Mi1 HalP+HalI &]
                            leitura = fread(app.portaSerial,13);
                            MatrizSensores = [ MatrizSensores; 0, leitura(2)*256+leitura(3),leitura(4)*256+leitura(5),leitura(6)*256+leitura(7),leitura(8)*256+leitura(9), leitura(10)*256+leitura(11), 0, 0];
                            captarDados=captarDados+1;
                            %disp(app.portaSerial.BytesAvailable);
                        end
                %Finaliza o envio do quadro pela luva
                    %disp(app.portaSerial.BytesAvailable);
                    num = '&';
                    fwrite(app.portaSerial, num, 'int8');
                    flushinput(app.portaSerial); 
             %Fim Captação de dados
             %inicio da tratativa das leituras
                    MatrizSensores(1,:)=[];
                    dimensoes=size(MatrizSensores);
                    app.ValoresReferencia.Value = mean(MatrizSensores);
                    app.DesvioReferencia.Value = std(MatrizSensores);
                    %disp(app.DesvioReferencia.Value);
                    %disp(app.ValoresReferencia.Value);
                    app.Calibrar.BackgroundColor = 'green';
                    app.Calibrar.Text = 'Calibrado';
                    app.Start.Enable = 'on';
                    app.StrDate.Value= replace(datestr(datetime()),':','.');
                    Caminho= strcat('D:\ifrs\11° periodo\TCC II\BasesDados\base',app.StrDate.Value,'.xls');
                    xlswrite(Caminho, MatrizSensores, 'Calibragem');
                    xlswrite(Caminho, app.ValoresReferencia.Value, 'ValoresReferencia','A2');
                    xlswrite(Caminho, app.DesvioReferencia.Value, 'ValoresReferencia','A3');
        end

        % Value changed function: Start
        function StartValueChanged(app, event)
            if app.Start.Value==1
                i=1;
                random(app);
                %disp(app.sequencia);
                app.Start.BackgroundColor = 'red';
            else
                app.Start.BackgroundColor = 'green';
            end
           
            ValorSensores=[0,0,0,0,0,0,0,0];
            ValorVariacao=[0,0,0,0,0,0,0,0];
            desvioRef=1.1*app.DesvioReferencia.Value;
            
            
            %inicializando MatrizSensores
                %Inicia o envio do quadro pela luva
                    flushinput(app.portaSerial)
                    num = '$';
                    fwrite(app.portaSerial, num, 'int8');
                    while(app.portaSerial.BytesAvailable==0)
                    end
                %coletando primeira leitura
                    while(app.portaSerial.BytesAvailable<14)
                    end
                    leitura = fread(app.portaSerial,13);
                    MatrizSensores = [i, leitura(2)*256+leitura(3),leitura(4)*256+leitura(5),leitura(6)*256+leitura(7),leitura(8)*256+leitura(9), leitura(10)*256+leitura(11), 0, 0];
                    matrizletra=[app.sequencia(i)];
                %Finaliza o envio do quadro pela luva
                    %disp(app.portaSerial.BytesAvailable);
                    num = '&';
                    fwrite(app.portaSerial, num, 'int8');
                    flushinput(app.portaSerial);    
             %Finalizando inicialização   
                     count=1;
            %Captação de sinais para treinamento
                while(app.Start.Value)
                    % Alterando imagem do sinal para captação
                        file = sprintf('%s%s.jpg',app.dir,app.sequencia(i));
                        image = imread(file);
                        imshow(image, 'parent', app.Imagem);
                        pause(1);
                        
                    % Inicio Captação de dados
                        %Inicia o envio do quadro pela luva
                            flushinput(app.portaSerial)
                            num = '$';
                            fwrite(app.portaSerial, num, 'int8');
                            while(app.portaSerial.BytesAvailable==0)
                            end
                        MatrizDesvio=3*app.DesvioReferencia.Value;
                        InicioMovimento=count;
                        app.ColetandoDados.BackgroundColor='green';
                        while(MatrizDesvio(2)>desvioRef(2)||MatrizDesvio(3)>desvioRef(3)||MatrizDesvio(4)>desvioRef(4)||MatrizDesvio(5)>desvioRef(5)||MatrizDesvio(6)>desvioRef(6))
                            captarDados=1;
                            while(captarDados<=50)
                                count=count+1;
                                while(app.portaSerial.BytesAvailable<14)
                                end
                                %Quadro [$ Po2 Po1 In2 In1 Me2 Me1 An2 An1 Mi2 Mi1 HalP+HalI &]
                                leitura = fread(app.portaSerial,13);
                                    %Hall
                                        if(leitura(12)==0)
                                            Hall1=0;Hall2=0;
                                        else
                                            if(leitura(12)==1)
                                                Hall1=1;Hall2=0;
                                            else
                                                if(leitura(12)==10)
                                                    Hall1=0;Hall2=1;
                                                else
                                                    Hall1=1;Hall2=1;
                                                end
                                            end
                                        end
                            
                                    %Fim hall
                                MatrizSensores = [MatrizSensores;i,leitura(2)*256+leitura(3),leitura(4)*256+leitura(5),leitura(6)*256+leitura(7),leitura(8)*256+leitura(9), leitura(10)*256+leitura(11), Hall1, Hall2];
                                matrizletra = [matrizletra;app.sequencia(i)];
                                captarDados=captarDados+1;
                            end
                            dimMatriz=size(MatrizSensores);
                            MatrizDesvio=std(MatrizSensores(dimMatriz(1)-49:dimMatriz(1),:));
                        end
                        FimMovimento=count
                        MatrizSensores(InicioMovimento:FimMovimento,1)
                        %Finaliza o envio do quadro pela luva
                            %disp(app.portaSerial.BytesAvailable);
                            num = '&';
                            fwrite(app.portaSerial, num, 'int8');
                            flushinput(app.portaSerial); 
                        app.ColetandoDados.BackgroundColor =[0.94 0.94 0.94];    
                     %matriz[Mi An Me In Po Hallp Halli]
                     %Valores unicos da leitura
                     ValorSensores=[ValorSensores;i,mean(MatrizSensores(dimMatriz(1)-49:dimMatriz(1),2:dimMatriz(2)))];
                     ValorVariacao=[ValorVariacao;i,mean(MatrizSensores(dimMatriz(1)-49:dimMatriz(1),2:dimMatriz(2))-app.ValoresReferencia.Value(2:dimMatriz(2)))];
                     %Plot dos valores lidos no sinal 
                         plot(app.Minimo,MatrizSensores(InicioMovimento:FimMovimento,2))
                         plot(app.Anelar,MatrizSensores(InicioMovimento:FimMovimento,3))
                         plot(app.Medio,MatrizSensores(InicioMovimento:FimMovimento,4))
                         plot(app.Indicador,MatrizSensores(InicioMovimento:FimMovimento,5))
                         plot(app.Polegar,MatrizSensores(InicioMovimento:FimMovimento,6))
                         plot(app.HALLPolegar, MatrizSensores(InicioMovimento:FimMovimento,7))
                         plot(app.HALLIndicador, MatrizSensores(InicioMovimento:FimMovimento,8))
                         pause(5);
                    %Fim Captação de dados
                    %Finaliza a amostragem automaticamente
                        if i == (app.qtdLetras*app.nAmostras.Value)
                            app.Start.Value = 0;%Desativa o botão pois atingiu o n° de amostras
                            app.Start.BackgroundColor = 'green';%devolve o botão para verde
                        end
                    % 'i' indica qual o próximo exemplo a ser captado
                        if i< app.qtdLetras*app.nAmostras.Value
                            i = i+1;
                        end    
                end
                %salvar arquivo
                    MatrizVatriacao=MatrizSensores-app.ValoresReferencia.Value;
                    %ValorSensores=(1,:)=[];
                    %ValorVariacao=(1,:)=[];
                    desc=['c','M','A','M','I','P','H','H','L'];
                    Caminho= strcat('D:\ifrs\11° periodo\TCC II\BasesDados\base',app.StrDate.Value,'.xls');
                    xlswrite(Caminho, desc, 'Sensores');
                    xlswrite(Caminho, MatrizSensores, 'Sensores','A2');
                    xlswrite(Caminho, matrizletra, 'Sensores', 'I2');


                    xlswrite(Caminho, desc, 'Variação');
                    xlswrite(Caminho, MatrizVatriacao, 'Variação','A2');
                    xlswrite(Caminho, matrizletra, 'Variação', 'I2');
                    
                    xlswrite(Caminho, desc, 'ValorSensores');
                    xlswrite(Caminho, ValorSensores, 'ValorSensores','A2');
                    xlswrite(Caminho, [0;app.sequencia(:)], 'ValorSensores', 'I2');
                    
                    xlswrite(Caminho, desc, 'ValorVariacao');
                    xlswrite(Caminho, ValorVariacao, 'ValorVariacao','A2');
                    xlswrite(Caminho, [0;app.sequencia(:)], 'ValorVariacao', 'I2');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Finalizando Serial
                fclose(app.portaSerial);
                delete(app.portaSerial);
                
            disp('Finalizando o programa...');
            %Finalização do programa
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Imagem = uiaxes(app.UIFigure);
            title(app.Imagem, 'Execute o sinal')
            app.Start = uibutton(app.UIFigure, 'state');
            app.Start.ValueChangedFcn = createCallbackFcn(app, @StartValueChanged, true);
            app.Start.Text = 'Iniciar';
            app.NrepetiesLabel = uilabel(app.UIFigure);
            app.NrepetiesLabel.Text = 'Nº repetições:';
            app.nAmostras = uieditfield(app.UIFigure, 'numeric');
            app.nAmostras.Limits = [1 10];
            app.nAmostras.Value = 1;
            app.Calibrar = uibutton(app.UIFigure, 'push');
            app.Calibrar.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.Calibrar.Text = 'Calibrar';
            app.Anelar = uiaxes(app.UIFigure);
            title(app.Anelar, 'Anelar')
            app.Medio = uiaxes(app.UIFigure);
            title(app.Medio, 'Médio')
            app.Indicador = uiaxes(app.UIFigure);
            title(app.Indicador, 'Indicador')
            app.Minimo = uiaxes(app.UIFigure);
            title(app.Minimo, 'Mínimo')
            app.HALLPolegar = uiaxes(app.UIFigure);
            title(app.HALLPolegar, 'Hall Polegar')
            app.Polegar = uiaxes(app.UIFigure);
            title(app.Polegar, 'Polegar')
            app.HALLIndicador = uiaxes(app.UIFigure);
            title(app.HALLIndicador, 'Hall Indicador')
            app.ColetandoDados = uibutton(app.UIFigure, 'state');
            app.ColetandoDados.Text = 'Coletando Dados';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Aplicacao

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end